classdef live_plotter_it3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        SaverunCheckBox            matlab.ui.control.CheckBox
        IterationEditField         matlab.ui.control.NumericEditField
        IterationEditFieldLabel    matlab.ui.control.Label
        TitleEditField             matlab.ui.control.EditField
        TitleEditFieldLabel        matlab.ui.control.Label
        MotorPanel                 matlab.ui.container.Panel
        PhaseEditField             matlab.ui.control.NumericEditField
        PhaseEditFieldLabel        matlab.ui.control.Label
        FreqEditField              matlab.ui.control.NumericEditField
        FreqEditFieldLabel         matlab.ui.control.Label
        AmpEditField               matlab.ui.control.NumericEditField
        AmpEditFieldLabel          matlab.ui.control.Label
        SENDButton                 matlab.ui.control.Button
        ControlPanel               matlab.ui.container.Panel
        Panel_2                    matlab.ui.container.Panel
        YaxislimitsEditField       matlab.ui.control.NumericEditField
        YaxislimitsEditFieldLabel  matlab.ui.control.Label
        XaxismaxEditField          matlab.ui.control.NumericEditField
        XaxismaxEditFieldLabel     matlab.ui.control.Label
        STOPButton                 matlab.ui.control.Button
        STARTButton                matlab.ui.control.Button
        UIAxes4                    matlab.ui.control.UIAxes
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        t % time
        Stop % stops the graphing
        fname % file name
        writefile % for saving data outside of backup log
        itval % loop iteration variable
        xmax % X axis max for all graphs
        ymax % y axis max for all graphs
        ymin % y axis min for all graphs
        freq % AO signal frequency
        amp % AO signal amplitude
        phase % AO signal phase
        d % daq object
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)

            % Reset from previous iterations
            app.Stop = false;
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
           
            % Initialize instrument readout
            app.d=daq("ni");
            addinput(app.d, "Dev1", 0:1, "Voltage");
            addinput(app.d, "Dev1", "ai2", "Voltage");
            addoutput(app.d, "Dev1", "ao0", "Voltage");
            app.d.Rate=5000;

            % Create table to store data
            dataTable = timetable();

            % Initialize time and iteration variables
            i=0;
            j=0;
            app.xmax = 100; % setting default x-axis upper limit
            app.ymax = 1;
            app.ymin = -1;
            app.amp = 1;
            app.phase = 0;
            app.freq = 1;
            tic
                     
            while true
                % Acquiring data
                time_el = seconds(toc);

                % Output signal
                outputSig = app.amp*sin(app.freq*linspace(0, 1, app.d.Rate)'+app.phase);

                data = readwrite(app.d, outputSig, "OutputFormat","Timetable");
                %data = read(app.d, seconds(1), OutputFormat="Timetable");
                

                % Total time
                data.Time = data.Time + time_el + seconds(i);
                tic

                % Plotting data
                plot(app.UIAxes, decimate(seconds(data.Time), 100), decimate(data.Dev1_ai0,100), 'k-.');
                xlim(app.UIAxes, [0 app.xmax]);
                ylim(app.UIAxes, [app.ymin app.ymax]);
                

                plot(app.UIAxes2, decimate(seconds(data.Time),100), decimate(data.Dev1_ai1,100), 'b-.');
                xlim(app.UIAxes2, [0 app.xmax]);
                ylim(app.UIAxes2, [app.ymin app.ymax]);
                
                
                plot(app.UIAxes3, decimate(seconds(data.Time),100), decimate(data.Dev1_ai2,100), 'r-.');
                xlim(app.UIAxes3, [0 app.xmax]);
                ylim(app.UIAxes3, [app.ymin app.ymax]);

                % Math plot
                data.ai0_angle = cos(data.Dev1_ai0 * 2.35); % Random conversion factor
                plot(app.UIAxes4, decimate(seconds(data.Time),100), decimate(data.ai0_angle,100), 'm-.');
                xlim(app.UIAxes4, [0 app.xmax]);
                ylim(app.UIAxes4, [app.ymin app.ymax]);
                

                % Write data to table
                dataTable = [dataTable; data];

                if app.Stop
                    break
                end

                i=i+1;
                j=j+1;
                
                % Backup log
                if j > 5
                    writetimetable(dataTable, 'liveplotter_backup.txt');
                    j=0;
                end
            end

            % Write table to file
            if app.writefile
                writetimetable(dataTable, strcat(app.fname, ...
                    int2str(app.itval), '.txt'));
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.Stop = true;
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            app.fname = app.TitleEditField.Value;
            
        end

        % Value changed function: IterationEditField
        function IterationEditFieldValueChanged(app, event)
            app.itval = app.IterationEditField.Value;
            
        end

        % Value changed function: SaverunCheckBox
        function SaverunCheckBoxValueChanged(app, event)
            app.writefile = app.SaverunCheckBox.Value;
            
        end

        % Value changed function: XaxismaxEditField
        function XaxismaxEditFieldValueChanged(app, event)
            app.xmax = seconds(app.XaxismaxEditField.Value);
            
        end

        % Value changed function: YaxislimitsEditField
        function YaxislimitsEditFieldValueChanged(app, event)
            app.ymax = app.YaxislimitsEditField.Value;
            app.ymin = -app.YaxislimitsEditField.Value;
            
        end

        % Callback function: AmpEditField, PhaseEditField, SENDButton
        function SENDButtonPushed(app, event)
            app.phase = app.PhaseEditField.Value;
            app.amp = app.AmpEditField.Value;
            app.freq = app.FreqEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pin 0')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'V')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Pin 1')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'V')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pin 2')
            xlabel(app.UIAxes3, 't')
            ylabel(app.UIAxes3, 'V')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Math')
            xlabel(app.UIAxes4, 't')
            ylabel(app.UIAxes4, 'cos(theta)')
            zlabel(app.UIAxes4, 't')
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control Panel';
            app.STARTButton = uibutton(app.ControlPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ControlPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.XaxismaxEditFieldLabel = uilabel(app.ControlPanel);
            app.XaxismaxEditFieldLabel.Text = 'X-axis max';
            app.XaxismaxEditField = uieditfield(app.ControlPanel, 'numeric');
            app.XaxismaxEditField.ValueChangedFcn = createCallbackFcn(app, @XaxismaxEditFieldValueChanged, true);
            app.YaxislimitsEditFieldLabel = uilabel(app.ControlPanel);
            app.YaxislimitsEditFieldLabel.Text = 'Y-axis limits';
            app.YaxislimitsEditField = uieditfield(app.ControlPanel, 'numeric');
            app.YaxislimitsEditField.ValueChangedFcn = createCallbackFcn(app, @YaxislimitsEditFieldValueChanged, true);
            app.Panel_2 = uipanel(app.ControlPanel);
            app.MotorPanel = uipanel(app.UIFigure);
            app.MotorPanel.Title = 'Motor';
            app.SENDButton = uibutton(app.MotorPanel, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.AmpEditFieldLabel = uilabel(app.MotorPanel);
            app.AmpEditFieldLabel.Text = 'Amp.';
            app.AmpEditField = uieditfield(app.MotorPanel, 'numeric');
            app.AmpEditField.ValueChangedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.FreqEditFieldLabel = uilabel(app.MotorPanel);
            app.FreqEditFieldLabel.Text = 'Freq.';
            app.FreqEditField = uieditfield(app.MotorPanel, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.MotorPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.MotorPanel, 'numeric');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.Panel = uipanel(app.UIFigure);
            app.TitleEditFieldLabel = uilabel(app.Panel);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.Panel, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.IterationEditFieldLabel = uilabel(app.Panel);
            app.IterationEditFieldLabel.Text = 'Iteration';
            app.IterationEditField = uieditfield(app.Panel, 'numeric');
            app.IterationEditField.ValueChangedFcn = createCallbackFcn(app, @IterationEditFieldValueChanged, true);
            app.SaverunCheckBox = uicheckbox(app.Panel);
            app.SaverunCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaverunCheckBoxValueChanged, true);
            app.SaverunCheckBox.Text = 'Save run?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = live_plotter_it3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end